classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BattDetectorAppUIFigure        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        StatusyPanel                   matlab.ui.container.Panel
        PoczeniezkamerLamp             matlab.ui.control.Lamp
        PoczeniezkamerLampLabel        matlab.ui.control.Label
        PoczeniezserwermLamp           matlab.ui.control.Lamp
        PoczeniezserwermLampLabel      matlab.ui.control.Label
        PracaLamp                      matlab.ui.control.Lamp
        PracaLampLabel                 matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        mainTab                        matlab.ui.container.Tab
        UruchomautomatycznieCheckBox   matlab.ui.control.CheckBox
        StartButton                    matlab.ui.control.Button
        UstawieniaTab_2                matlab.ui.container.Tab
        UstawieniakameryPanel          matlab.ui.container.Panel
        Kameranr2DropDown              matlab.ui.control.DropDown
        Kameranr2DropDownLabel         matlab.ui.control.Label
        PoczautomatyczniezestaretmaplikacjiCheckBox  matlab.ui.control.CheckBox
        Kameranr1DropDown              matlab.ui.control.DropDown
        Kameranr1DropDownLabel         matlab.ui.control.Label
        PoczzkamerButton               matlab.ui.control.Button
        SerwertcpPanel                 matlab.ui.container.Panel
        PoczzserweremButton            matlab.ui.control.Button
        PoczautomatyczniezserweremCheckBox  matlab.ui.control.CheckBox
        portserweratcpEditField        matlab.ui.control.EditField
        portserweratcpEditFieldLabel   matlab.ui.control.Label
        adresipserweratcpEditField     matlab.ui.control.EditField
        adresipserweratcpEditFieldLabel  matlab.ui.control.Label
        UstawieniaobrazuTab            matlab.ui.container.Tab
        detekcjaButton                 matlab.ui.control.Button
        fotkadotestuEditField          matlab.ui.control.EditField
        fotkadotestuEditFieldLabel     matlab.ui.control.Label
        WybierzzdjButton               matlab.ui.control.Button
        WybranasiecEditField           matlab.ui.control.EditField
        WybranasiecEditFieldLabel      matlab.ui.control.Label
        WybierzsiecButton              matlab.ui.control.Button
        WybierzmaskeButton             matlab.ui.control.Button
        WybranamaskaEditField          matlab.ui.control.EditField
        WybranamaskaEditFieldLabel     matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        KameryTab                      matlab.ui.container.Tab
        Kamera2Label                   matlab.ui.control.Label
        ImageCam2                      matlab.ui.control.Image
        ImageCam1                      matlab.ui.control.Image
        Kamera1Label                   matlab.ui.control.Label
        DetekcjaTab                    matlab.ui.container.Tab
        UszkodzonacelaPanel            matlab.ui.container.Panel
        uszkodzonaCelaFot              matlab.ui.control.Image
        CzasostatenigotestuEditField   matlab.ui.control.EditField
        CzasostatenigotestuEditFieldLabel  matlab.ui.control.Label
        UszkodzonepakietyEditField     matlab.ui.control.EditField
        UszkodzonepakietyEditFieldLabel  matlab.ui.control.Label
        PoprawniezgrzanepakietyEditField  matlab.ui.control.EditField
        PoprawniezgrzanepakietyEditFieldLabel  matlab.ui.control.Label
        SprawdzonepakietyEditField     matlab.ui.control.EditField
        SprawdzonepakietyEditFieldLabel  matlab.ui.control.Label
        WynikostatniegotestuEditField  matlab.ui.control.EditField
        WynikostatniegotestuEditFieldLabel  matlab.ui.control.Label
        PodgldTab                      matlab.ui.container.Tab
        MaskaLabel                     matlab.ui.control.Label
        maskaPodglad                   matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        LampOffColor    = [1.00, 0.00, 0.00];
        LampOnColor     = [0.00, 1.00, 0.00];
        imgaeSize = [300, 600];

        % timers
        updateControllsTimer =  timer('Period', 0.5, ...
                                'ExecutionMode', 'fixedSpacing', 'StartDelay', 1);
        tcpTimer = timer('Period', (1/25), ...
                                'ExecutionMode', 'fixedSpacing', 'StartDelay', 1);
        camTimer = timer('Period', (1/25), ...
                                'ExecutionMode', 'fixedSpacing', 'StartDelay', 1);
        cam1 = 0;
        cam2 = 0;
        camIsConnected = false;

        tcpConnection = tcpClient();
        settings = [];
        debug = true;

        net = 0;

        mask = [];


        checkedBatt = 0;
        failedBatt = 0;
        successBatt = 0;

    end
    methods (Access = public)
        function updateControllsTimerFcn(app, obj, ev)
            app.PracaLamp.Color = app.LampOffColor;
            
            if app.tcpConnection.isConnected
                app.PoczeniezserwermLamp.Color = app.LampOnColor;
            else 
                app.PoczeniezserwermLamp.Color = app.LampOffColor;
            end

            if app.camIsConnected
                app.PoczeniezkamerLamp.Color = app.LampOnColor;
            else 
                app.PoczeniezkamerLamp.Color = app.LampOffColor;
            end
        end
        function updateCams(app, obj, ev)
            img = imresize(snapshot(app.cam1), app.imgaeSize);
            app.ImageCam1.ImageSource = img;
            app.ImageCam2.ImageSource = img;
        end
        function updateTcp(app, obj, ev)
            data = app.tcpConnection.readData();
            disp(data);
            if length(data) > 1
                disp("len 5");
                if data(1) == 5
                    res = app.runDetector();
        
                    if res 
                        dataToSend = int8([6, 1]);    
                    else
                        dataToSend = int8([6, 0]); 
                    end
        
                    app.tcpConnection.sendData(dataToSend, "int8");
                end
            end

        end

        function connectWithCam(app, cam1, cam2)
            try
                app.cam1 = webcam(cam1);
                %app.cam2 = webcam(cam2);
                img = imresize(snapshot(app.cam1), app.imgaeSize);
                app.ImageCam1.ImageSource = img;
                app.ImageCam2.ImageSource = img;
                start(app.camTimer);
            catch ex
                msgbox(ex.message, "Błąd!", "error");
                disp(ex);
                app.camIsConnected = false;
                return;
            end
            app.camIsConnected = true;
        end
        function [frame1, frame2] = getSnapshotFromCam(app, size)
            if app.debug
                % losuj dwe fotki z folderu
                frame1 = imread(app.fotkadotestuEditField.Value);
                frame1 = imresize(frame1, size);
                frame2 = frame1;
            else
                % pobierz z kamer
                frame1 = imresize(snapshot(app.cam1), size);
                frame2 = frame1;
            end
        end

        function result = runDetector(app)
            result = true;
            app.checkedBatt = app.checkedBatt + 1;
           
            % pobranie obrazów z kamer
            timStart = tic;
            si = size(app.mask);
            [fr1 fr2] = app.getSnapshotFromCam(si(1:2));

            % pociecie obrazu zgodnie z maska 
            imageData = regionprops(~app.mask);
            
            for i = 1:length(imageData)
                dd = imageData(i).BoundingBox;
                newImg = fr1(floor(dd(2)):floor(dd(2)+(dd(4))), floor(dd(1)):floor(dd(1) + dd(3)), :);
                newImg = imresize(newImg, app.net.inputSize);
                
                % weryfikacja poszczególnych zgrzewow
                pred = classify(app.net.net, newImg);
                if(isempty(pred))
                     pred = 0;
                end

                if (~strcmp(string(pred), "ok"))
                    result = false;
                    app.uszkodzonaCelaFot.ImageSource = newImg;
                    break;
                else
                    app.uszkodzonaCelaFot.ImageSource = ones([si 3]);
                end
                

                if(isempty(pred))
                     pred = 0;
                end
            end
            timeEnd = toc(timStart);

            
            if result
                app.WynikostatniegotestuEditField.Value = "ok";
                app.successBatt = app.successBatt + 1;
            else 
                app.WynikostatniegotestuEditField.Value = "fail";
                app.failedBatt = app.failedBatt + 1;
            end
            app.CzasostatenigotestuEditField.Value = string(timeEnd);

            app.SprawdzonepakietyEditField.Value     = string(app.checkedBatt);
            app.PoprawniezgrzanepakietyEditField.Value      = string(app.successBatt);
            app.UszkodzonepakietyEditField.Value    = string(app.failedBatt);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updateControllsTimer.TimerFcn = @app.updateControllsTimerFcn;
            app.camTimer.TimerFcn = @app.updateCams;
            app.tcpTimer.TimerFcn = @app.updateTcp;

            start(app.updateControllsTimer);

            % wczytanie zapisanych danych 
            app.settings = load("settings.mat");

            app.adresipserweratcpEditField.Value    = app.settings.tcpServerIp;
            app.portserweratcpEditField.Value       = app.settings.tcpServerPort;

            app.PoczautomatyczniezserweremCheckBox.Value            = app.settings.tcpServerAutoConnetct;
            app.PoczautomatyczniezestaretmaplikacjiCheckBox.Value   = app.settings.camAutoConnect;

            app.WybranamaskaEditField.Value = app.settings.savedMask;
            app.WybranasiecEditField.Value = app.settings.selectedNet;

            % pobranie kamer
            cams = webcamlist();
            app.Kameranr1DropDown.Items = cams;
            app.Kameranr2DropDown.Items = cams;

            % wczytanie sieci i maski
            try 
                app.net = load(app.WybranasiecEditField.Value);
                app.mask = imread(app.WybranamaskaEditField.Value);

                app.maskaPodglad.ImageSource = app.mask;
                app.mask = im2bw(app.mask);
            catch 
                msgbox("Nie udalo sie wczytac maski lub sieci");
                app.settings.camAutoConnect = false;
            end

            %ustawienie wybranych wartosci
            if sum(strcmp(string(app.Kameranr1DropDown.Items), app.settings.camSelected1)) >= 1
                app.Kameranr1DropDown.Value = app.settings.camSelected1;
            end
            if sum(strcmp(string(app.Kameranr2DropDown.Items), app.settings.camSelected2)) >= 1
                app.Kameranr2DropDown.Value = app.settings.camSelected2;
            end

            %autostart
            if(app.settings.camAutoConnect == true)
                % connect to cams
                
                camName = app.Kameranr1DropDown.Value;
                app.connectWithCam(camName, camName);
            end
            % auto connect to tcp client
            if(app.settings.tcpServerAutoConnetct)
                app.tcpConnection = app.tcpConnection.connect(app.adresipserweratcpEditField.Value, app.portserweratcpEditField.Value);
                start(app.tcpTimer);
            end


        end

        % Close request function: BattDetectorAppUIFigure
        function BattDetectorAppUIFigureCloseRequest(app, event)
            stop(app.updateControllsTimer);
            
            app.settings.tcpServerIp = app.adresipserweratcpEditField.Value;
            app.settings.tcpServerPort = app.portserweratcpEditField.Value;
            app.settings.tcpServerAutoConnetct =app.PoczautomatyczniezserweremCheckBox.Value;
            app.settings.camAutoConnect = app.PoczautomatyczniezestaretmaplikacjiCheckBox.Value;
            app.settings.camSelected1 = app.Kameranr1DropDown.Value;
            app.settings.camSelected2 = app.Kameranr2DropDown.Value;

            app.settings.savedMask      = app.WybranamaskaEditField.Value;
            app.settings.selectedNet    = app.WybranasiecEditField.Value;

            settings = app.settings;
            save("settings.mat", "-struct", "settings");
            delete(app);            
        end

        % Button pushed function: PoczzkamerButton
        function PoczzkamerButtonPushed(app, event)
            camName = app.Kameranr1DropDown.Value;
            app.connectWithCam(camName, camName);
        end

        % Button pushed function: PoczzserweremButton
        function PoczzserweremButtonPushed(app, event)
            app.tcpConnection = app.tcpConnection.connect(app.adresipserweratcpEditField.Value, app.portserweratcpEditField.Value);
            start(app.tcpTimer);
        end

        % Button pushed function: WybierzmaskeButton
        function WybierzmaskeButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg';'*.png'; '*.*'});
            fullPath = [path, file];
            app.mask = imread(fullPath);

            app.WybranamaskaEditField.Value = fullPath;
            app.maskaPodglad.ImageSource = app.mask;
            app.mask = im2bw(app.mask);
        end

        % Button pushed function: WybierzsiecButton
        function WybierzsiecButtonPushed(app, event)
            [file, path] = uigetfile({'*.mat';'*.*'});
            fullPath = [path, file];
            app.WybranasiecEditField.Value = fullPath;

            app.net = load(fullPath);
        end

        % Button pushed function: detekcjaButton
        function detekcjaButtonPushed(app, event)
            app.runDetector();
        end

        % Button pushed function: WybierzzdjButton
        function WybierzzdjButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg';'*.png'; '*.*'});
            fullPath = [path, file];
            app.fotkadotestuEditField.Value = fullPath;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BattDetectorAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {669, 669};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {331, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BattDetectorAppUIFigure = uifigure('Visible', 'off');
            app.BattDetectorAppUIFigure.Name = 'BattDetector App';
            app.BattDetectorAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @BattDetectorAppUIFigureCloseRequest, true);
            app.BattDetectorAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BattDetectorAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.mainTab = uitab(app.TabGroup);
            app.mainTab.Title = 'main';
            app.StartButton = uibutton(app.mainTab, 'push');
            app.StartButton.Text = 'Start';
            app.UruchomautomatycznieCheckBox = uicheckbox(app.mainTab);
            app.UruchomautomatycznieCheckBox.Text = 'Uruchom automatycznie';
            app.UstawieniaTab_2 = uitab(app.TabGroup);
            app.UstawieniaTab_2.Title = 'Ustawienia';
            app.SerwertcpPanel = uipanel(app.UstawieniaTab_2);
            app.SerwertcpPanel.Title = 'Serwer tcp';
            app.adresipserweratcpEditFieldLabel = uilabel(app.SerwertcpPanel);
            app.adresipserweratcpEditFieldLabel.Text = 'adres ip serwera tcp';
            app.adresipserweratcpEditField = uieditfield(app.SerwertcpPanel, 'text');
            app.portserweratcpEditFieldLabel = uilabel(app.SerwertcpPanel);
            app.portserweratcpEditFieldLabel.Text = 'port serwera tcp';
            app.portserweratcpEditField = uieditfield(app.SerwertcpPanel, 'text');
            app.PoczautomatyczniezserweremCheckBox = uicheckbox(app.SerwertcpPanel);
            app.PoczautomatyczniezserweremCheckBox.Text = 'Połącz automatycznie z serwerem';
            app.PoczzserweremButton = uibutton(app.SerwertcpPanel, 'push');
            app.PoczzserweremButton.ButtonPushedFcn = createCallbackFcn(app, @PoczzserweremButtonPushed, true);
            app.PoczzserweremButton.Text = 'Połącz z serwerem';
            app.UstawieniakameryPanel = uipanel(app.UstawieniaTab_2);
            app.UstawieniakameryPanel.Title = 'Ustawienia kamery';
            app.PoczzkamerButton = uibutton(app.UstawieniakameryPanel, 'push');
            app.PoczzkamerButton.ButtonPushedFcn = createCallbackFcn(app, @PoczzkamerButtonPushed, true);
            app.PoczzkamerButton.Text = 'Połącz z kamerą';
            app.Kameranr1DropDownLabel = uilabel(app.UstawieniakameryPanel);
            app.Kameranr1DropDownLabel.Text = 'Kamera nr. 1';
            app.Kameranr1DropDown = uidropdown(app.UstawieniakameryPanel);
            app.PoczautomatyczniezestaretmaplikacjiCheckBox = uicheckbox(app.UstawieniakameryPanel);
            app.PoczautomatyczniezestaretmaplikacjiCheckBox.Text = 'Połącz automatycznie ze staretm aplikacji';
            app.Kameranr2DropDownLabel = uilabel(app.UstawieniakameryPanel);
            app.Kameranr2DropDownLabel.Text = 'Kamera nr. 2';
            app.Kameranr2DropDown = uidropdown(app.UstawieniakameryPanel);
            app.UstawieniaobrazuTab = uitab(app.TabGroup);
            app.UstawieniaobrazuTab.Title = 'Ustawienia obrazu';
            app.WybranamaskaEditFieldLabel = uilabel(app.UstawieniaobrazuTab);
            app.WybranamaskaEditFieldLabel.Text = 'Wybrana maska';
            app.WybranamaskaEditField = uieditfield(app.UstawieniaobrazuTab, 'text');
            app.WybierzmaskeButton = uibutton(app.UstawieniaobrazuTab, 'push');
            app.WybierzmaskeButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzmaskeButtonPushed, true);
            app.WybierzmaskeButton.Text = 'Wybierz maske';
            app.WybierzsiecButton = uibutton(app.UstawieniaobrazuTab, 'push');
            app.WybierzsiecButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzsiecButtonPushed, true);
            app.WybierzsiecButton.Text = 'Wybierz siec';
            app.WybranasiecEditFieldLabel = uilabel(app.UstawieniaobrazuTab);
            app.WybranasiecEditFieldLabel.Text = 'Wybrana siec';
            app.WybranasiecEditField = uieditfield(app.UstawieniaobrazuTab, 'text');
            app.WybierzzdjButton = uibutton(app.UstawieniaobrazuTab, 'push');
            app.WybierzzdjButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzzdjButtonPushed, true);
            app.WybierzzdjButton.Text = 'Wybierz zdj';
            app.fotkadotestuEditFieldLabel = uilabel(app.UstawieniaobrazuTab);
            app.fotkadotestuEditFieldLabel.Text = 'fotka do testu';
            app.fotkadotestuEditField = uieditfield(app.UstawieniaobrazuTab, 'text');
            app.detekcjaButton = uibutton(app.UstawieniaobrazuTab, 'push');
            app.detekcjaButton.ButtonPushedFcn = createCallbackFcn(app, @detekcjaButtonPushed, true);
            app.detekcjaButton.Text = 'detekcja';
            app.StatusyPanel = uipanel(app.LeftPanel);
            app.StatusyPanel.Title = 'Statusy';
            app.PracaLampLabel = uilabel(app.StatusyPanel);
            app.PracaLampLabel.Text = 'Praca';
            app.PracaLamp = uilamp(app.StatusyPanel);
            app.PoczeniezserwermLampLabel = uilabel(app.StatusyPanel);
            app.PoczeniezserwermLampLabel.Text = 'Połączenie z serwerm';
            app.PoczeniezserwermLamp = uilamp(app.StatusyPanel);
            app.PoczeniezkamerLampLabel = uilabel(app.StatusyPanel);
            app.PoczeniezkamerLampLabel.Text = 'Połączenie z kamerą';
            app.PoczeniezkamerLamp = uilamp(app.StatusyPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.KameryTab = uitab(app.TabGroup2);
            app.KameryTab.Title = 'Kamery';
            app.Kamera1Label = uilabel(app.KameryTab);
            app.Kamera1Label.Text = 'Kamera 1';
            app.ImageCam1 = uiimage(app.KameryTab);
            app.ImageCam1.ImageSource = fullfile(pathToMLAPP, 'assets', 'camNotFound.png');
            app.ImageCam2 = uiimage(app.KameryTab);
            app.ImageCam2.ImageSource = fullfile(pathToMLAPP, 'assets', 'camNotFound.png');
            app.Kamera2Label = uilabel(app.KameryTab);
            app.Kamera2Label.Text = 'Kamera 2';
            app.DetekcjaTab = uitab(app.TabGroup2);
            app.DetekcjaTab.Title = 'Detekcja ';
            app.WynikostatniegotestuEditFieldLabel = uilabel(app.DetekcjaTab);
            app.WynikostatniegotestuEditFieldLabel.Text = 'Wynik ostatniego testu ';
            app.WynikostatniegotestuEditField = uieditfield(app.DetekcjaTab, 'text');
            app.WynikostatniegotestuEditField.Editable = 'off';
            app.SprawdzonepakietyEditFieldLabel = uilabel(app.DetekcjaTab);
            app.SprawdzonepakietyEditFieldLabel.Text = 'Sprawdzone  pakiety';
            app.SprawdzonepakietyEditField = uieditfield(app.DetekcjaTab, 'text');
            app.SprawdzonepakietyEditField.Editable = 'off';
            app.PoprawniezgrzanepakietyEditFieldLabel = uilabel(app.DetekcjaTab);
            app.PoprawniezgrzanepakietyEditFieldLabel.Text = 'Poprawnie zgrzane pakiety';
            app.PoprawniezgrzanepakietyEditField = uieditfield(app.DetekcjaTab, 'text');
            app.PoprawniezgrzanepakietyEditField.Editable = 'off';
            app.UszkodzonepakietyEditFieldLabel = uilabel(app.DetekcjaTab);
            app.UszkodzonepakietyEditFieldLabel.Text = 'Uszkodzone pakiety';
            app.UszkodzonepakietyEditField = uieditfield(app.DetekcjaTab, 'text');
            app.UszkodzonepakietyEditField.Editable = 'off';
            app.CzasostatenigotestuEditFieldLabel = uilabel(app.DetekcjaTab);
            app.CzasostatenigotestuEditFieldLabel.Text = 'Czas ostatenigo testu';
            app.CzasostatenigotestuEditField = uieditfield(app.DetekcjaTab, 'text');
            app.CzasostatenigotestuEditField.Editable = 'off';
            app.UszkodzonacelaPanel = uipanel(app.DetekcjaTab);
            app.UszkodzonacelaPanel.Title = 'Uszkodzona cela';
            app.uszkodzonaCelaFot = uiimage(app.UszkodzonacelaPanel);
            app.PodgldTab = uitab(app.TabGroup2);
            app.PodgldTab.Title = 'Podgląd';
            app.maskaPodglad = uiimage(app.PodgldTab);
            app.MaskaLabel = uilabel(app.PodgldTab);
            app.MaskaLabel.Text = 'Maska';
            % Show the figure after all components are created
            app.BattDetectorAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BattDetectorAppUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.BattDetectorAppUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BattDetectorAppUIFigure)
        end
    end
end